classdef SpectralTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralProcessingToolUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ImportededsignalButton          matlab.ui.control.ToggleButton
        DetrendedsignalButton           matlab.ui.control.ToggleButton
        DetrendingSwitch                matlab.ui.control.Switch
        DetrendingSwitchLabel           matlab.ui.control.Label
        EnvelopeSwitch                  matlab.ui.control.Switch
        EnvelopeSwitchLabel             matlab.ui.control.Label
        DegreeofpolynomialtrendEditField  matlab.ui.control.NumericEditField
        DegreeofpolynomialtrendEditFieldLabel  matlab.ui.control.Label
        AnalysisButton                  matlab.ui.control.Button
        ChoosefileDropDown              matlab.ui.control.DropDown
        ChoosefileDropDownLabel         matlab.ui.control.Label
        UIAxes_InsFreq                  matlab.ui.control.UIAxes
        UIAxes_Env                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FrequenceDomainTab              matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        WindowNum                       matlab.ui.control.NumericEditField
        WindowlengthofsignallengthSlider  matlab.ui.control.Slider
        WindowlengthofsignallengthSliderLabel  matlab.ui.control.Label
        WindowgainEditField             matlab.ui.control.NumericEditField
        WindowgainEditFieldLabel        matlab.ui.control.Label
        OverlapfactorEditField          matlab.ui.control.NumericEditField
        OverlapfactorLabel              matlab.ui.control.Label
        PSDmetodButtonGroup             matlab.ui.container.ButtonGroup
        WelchButton                     matlab.ui.control.ToggleButton
        PeriodogramButton               matlab.ui.control.ToggleButton
        WindowButtonGroup               matlab.ui.container.ButtonGroup
        HammingButton                   matlab.ui.control.ToggleButton
        HannButton                      matlab.ui.control.ToggleButton
        UIAxes_Freq                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        file;
        detrend;
        signal;
        fs;
        inst_amplitude;
        inst_phase;
        inst_freq;
        window_length;
        gain;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosefileDropDown
        function ChoosefileDropDownValueChanged(app, event)
            value = app.ChoosefileDropDown.Value;
            switch value
                case "Example 1"
                    app.file = importdata("signals\Polynomal_Trended_1.mat");
                case "Example 2"
                    app.file = importdata("signals\Sawtooth_Trended.mat");
                case "Example 3"
                    app.file = importdata("signals\Sin_Noised.mat");
                case "Choose from disk"
                                [app.file,path] = uigetfile('*.mat');
                                if isequal(app.file,0)
                                disp('User selected Cancel');
                                else
                                disp(['User selected ', fullfile(path,app.file)]);
                                end
                                app.file = importdata(fullfile(path,app.file));
            end

            plot(app.UIAxes,app.file.t,app.file.x);  
            app.fs = 1/(app.file.t(2)-app.file.t(1));

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           % plot(app.UIAxes,file.t,file.x);
        end

        % Value changed function: DetrendingSwitch
        function DetrendingSwitchValueChanged(app, event)
            value = app.DetrendingSwitch.Value;
            if value == "On"
                app.DegreeofpolynomialtrendEditField.Enable = "on";
                app.ButtonGroup.Enable = "on";
                app.ButtonGroup.Visible = "on";
              

            elseif value == "Off"
                app.DegreeofpolynomialtrendEditField.Enable = "off";
                app.ButtonGroup.Enable = "off";
                app.ButtonGroup.Visible = "off";
                plot(app.UIAxes,app.file.t,app.file.x); 
            end
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            addpath("func\");
            if app.DetrendingSwitch.Value == "On"
                [app.detrend,trend] = Detrend(app.file.t,app.file.x,app.DegreeofpolynomialtrendEditField.Value);
                plot(app.UIAxes,app.file.t,app.file.x);  
                app.UIAxes.NextPlot='add';
                plot(app.UIAxes,app.file.t,trend,"LineStyle","--");
                plot(app.UIAxes,app.file.t,app.detrend);
                app.UIAxes.NextPlot='replace';
                app.signal=app.detrend;
                app.UIAxes.Title.String = "Data in Time Domain";
            else
                app.signal = app.file.x;
            end
            if app.EnvelopeSwitch.Value == "On"
                if (app.ImportededsignalButton.Value == true)
                    [yupper,  ylower] = Envelope(app.file.x);
                    plot(app.UIAxes_Env,app.file.t,app.file.x);
                    app.UIAxes_Env.NextPlot='add';
                    plot(app.UIAxes_Env,app.file.t,ylower);
                    plot(app.UIAxes_Env,app.file.t,yupper);
                    app.UIAxes_Env.NextPlot='replace';
                    app.UIAxes_Env.Title.String = "Envelope";
                elseif (app.DetrendedsignalButton.Value == true)
                    [yupper,  ylower] = Envelope(app.detrend);
                    plot(app.UIAxes_Env,app.file.t,app.detrend);
                    app.UIAxes_Env.NextPlot='add';
                    plot(app.UIAxes_Env,app.file.t,ylower);
                    plot(app.UIAxes_Env,app.file.t,yupper);
                    app.UIAxes_Env.NextPlot='replace';
                    app.UIAxes_Env.Title.String = "Envelope";
                end
            end
            if app.DetrendingSwitch.Value == "On"
                if (app.ImportededsignalButton.Value == true)
                    [app.inst_amplitude,app.inst_phase,app.inst_freq]...
                        = Instinfo(app.file.x,app.fs);
                    T_diff =  app.file.t(1:end-1)-1/app.fs/2;
                    plot(app.UIAxes_InsFreq, T_diff ,app.inst_freq);
                elseif (app.DetrendedsignalButton.Value == true)
                    [app.inst_amplitude,app.inst_phase,app.inst_freq] =...
                        Instinfo(app.signal,app.fs);
                    T_diff =  app.file.t(1:end-1)-1/app.fs/2;
                    plot(app.UIAxes_InsFreq, T_diff,app.inst_freq);
                end
            end
            [app.inst_amplitude,app.inst_phase,app.inst_freq]=...
                Instinfo(app.file.x,app.fs);
            T_diff =  app.file.t(1:end-1)-1/app.fs/2;
            plot(app.UIAxes_InsFreq, T_diff ,app.inst_freq);
        end

        % Value changed function: EnvelopeSwitch
        function EnvelopeSwitchValueChanged(app, event)
            value = app.EnvelopeSwitch.Value;
        if (value == "Off")
            cla(app.UIAxes_Env,'reset')
        end
        end

        % Selection changed function: PSDmetodButtonGroup
        function PSDmetodButtonGroupSelectionChanged(app, event)
            if (app.WelchButton.Value == true)
                app.WindowButtonGroup.Visible = "on";
                app.WindowButtonGroup.Enable = "on";
                app.WindowNum.Visible = "on";
                app.WindowlengthofsignallengthSlider.Visible = "on";
                app.WindowlengthofsignallengthSliderLabel.Visible ="on";
                app.OverlapfactorEditField.Visible = "on";
                app.OverlapfactorLabel.Visible ="on";
                app.WindowgainEditFieldLabel.Visible ="on";
                app.WindowgainEditField.Visible = "on";
                app.WindowNum.Enable = "on";
                app.WindowlengthofsignallengthSlider.Enable = "on";
                app.WindowlengthofsignallengthSliderLabel.Enable ="on";
                app.OverlapfactorEditField.Enable = "on";
                app.OverlapfactorLabel.Enable ="on";
                app.WindowgainEditFieldLabel.Enable ="on";
                app.WindowgainEditField.Enable = "on";
                app.window_length=app.WindowNum.Value;
                app.UIAxes_Freq.Title.String = "Welch Power Spectral Density Estimate";
                app.UIAxes_Freq.XLabel.String =  "Frequency (Hz)";
                app.UIAxes_Freq.YLabel.String =  "Power / Frequency (db/Hz)";
                axis(app.UIAxes_Freq, 'tight');
                
            elseif (app.PeriodogramButton.Value == true)
             app.WindowButtonGroup.Visible = "off";
                app.WindowButtonGroup.Enable = "off";
                app.WindowNum.Visible = "off";
                app.WindowlengthofsignallengthSlider.Visible = "off";
                app.WindowlengthofsignallengthSliderLabel.Visible ="off";
                app.OverlapfactorEditField.Visible = "off";
                app.OverlapfactorLabel.Visible ="off";
                app.WindowgainEditFieldLabel.Visible ="off";
                app.WindowgainEditField.Visible = "off";
                app.WindowNum.Enable = "off";
                app.WindowlengthofsignallengthSlider.Enable = "off";
                app.WindowlengthofsignallengthSliderLabel.Enable ="off";
                app.OverlapfactorEditField.Enable = "off";
                app.OverlapfactorLabel.Enable ="off";
                app.WindowgainEditFieldLabel.Enable ="off";
                app.WindowgainEditField.Enable = "off";
               
            end
        end

        % Value changed function: WindowlengthofsignallengthSlider
        function WindowlengthofsignallengthSliderValueChanged(app, event)
        value = app.WindowlengthofsignallengthSlider.Value;
            app.window_length = value;
            app.WindowNum.Value =value;
            app.WindowlengthofsignallengthSlider.Value=value;
            
        end

        % Value changed function: WindowNum
        function WindowNumValueChanged(app, event)
            value = app.WindowNum.Value;
            app.window_length = value;
            app.WindowNum.Value =value;
            app.WindowlengthofsignallengthSlider.Value=value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            addpath("func\");
            app.gain = app.WindowgainEditField.Value;
            if (app.WelchButton.Value == true)
                Data1=app.signal;
                Data2=[];
                if (app.HannButton.Value == true)
                    window=Hann(ceil(app.window_length/100*length(app.signal)));
                elseif (app.HammingButton.Value == true)
                    window=Hamming(ceil(app.window_length/100*length(app.signal)));
                end
                Window = window*app.gain;
                OverlapFactor=app.OverlapfactorEditField.Value;
                SamplingFrequency=app.fs;
                [Power, Frequency] = WelchPSD(Data1, Data2, Window, OverlapFactor, SamplingFrequency);
                plot(app.UIAxes_Freq,Frequency, 10*log10(abs(Power)))
            end
        end

        % Value changed function: WindowgainEditField
        function WindowgainEditFieldValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SpectralProcessingToolUIFigure = uifigure('Visible', 'off');
            app.SpectralProcessingToolUIFigure.Name = 'Spectral Processing Tool';
            app.SpectralProcessingToolUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.TabGroup = uitabgroup(app.SpectralProcessingToolUIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Data in Time Domain ')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Amplitude [~]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_Env = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_Env, 'Envelope')
            xlabel(app.UIAxes_Env, 'Time [s]')
            ylabel(app.UIAxes_Env, 'Amplitude [~]')
            zlabel(app.UIAxes_Env, 'Z')
            app.UIAxes_InsFreq = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_InsFreq, 'Instantaneous Frequency')
            xlabel(app.UIAxes_InsFreq, 'Time [s]')
            ylabel(app.UIAxes_InsFreq, 'Frequency [Hz]')
            zlabel(app.UIAxes_InsFreq, 'Z')
            app.ChoosefileDropDownLabel = uilabel(app.TimeDomainTab);
            app.ChoosefileDropDownLabel.Text = 'Choose file';
            app.ChoosefileDropDown = uidropdown(app.TimeDomainTab);
            app.ChoosefileDropDown.Items = {'Import data', 'Example 1', 'Example 2', 'Example 3', 'Choose from disk'};
            app.ChoosefileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefileDropDownValueChanged, true);
            app.ChoosefileDropDown.Placeholder = '...';
            app.ChoosefileDropDown.Value = 'Import data';
            app.AnalysisButton = uibutton(app.TimeDomainTab, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.DegreeofpolynomialtrendEditFieldLabel = uilabel(app.TimeDomainTab);
            app.DegreeofpolynomialtrendEditFieldLabel.Text = 'Degree of polynomial trend';
            app.DegreeofpolynomialtrendEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.DegreeofpolynomialtrendEditField.Limits = [0 Inf];
            app.DegreeofpolynomialtrendEditField.RoundFractionalValues = 'on';
            app.DegreeofpolynomialtrendEditField.ValueDisplayFormat = '%1g';
            app.DegreeofpolynomialtrendEditField.Enable = 'off';
            app.DegreeofpolynomialtrendEditField.Value = 1;
            app.EnvelopeSwitchLabel = uilabel(app.TimeDomainTab);
            app.EnvelopeSwitchLabel.Text = 'Envelope';
            app.EnvelopeSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.EnvelopeSwitch.ValueChangedFcn = createCallbackFcn(app, @EnvelopeSwitchValueChanged, true);
            app.DetrendingSwitchLabel = uilabel(app.TimeDomainTab);
            app.DetrendingSwitchLabel.Text = 'Detrending';
            app.DetrendingSwitch = uiswitch(app.TimeDomainTab, 'slider');
            app.DetrendingSwitch.ValueChangedFcn = createCallbackFcn(app, @DetrendingSwitchValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.TimeDomainTab);
            app.ButtonGroup.Enable = 'off';
            app.ButtonGroup.Visible = 'off';
            app.DetrendedsignalButton = uitogglebutton(app.ButtonGroup);
            app.DetrendedsignalButton.Text = 'Detrended signal';
            app.ImportededsignalButton = uitogglebutton(app.ButtonGroup);
            app.ImportededsignalButton.Text = 'Importeded signal';
            app.ImportededsignalButton.Value = true;
            app.FrequenceDomainTab = uitab(app.TabGroup);
            app.FrequenceDomainTab.Title = 'Frequence Domain';
            app.UIAxes_Freq = uiaxes(app.FrequenceDomainTab);
            title(app.UIAxes_Freq, 'Title')
            xlabel(app.UIAxes_Freq, 'Frequence [Hz]')
            ylabel(app.UIAxes_Freq, 'Amplitude [~]')
            zlabel(app.UIAxes_Freq, 'Z')
            app.WindowButtonGroup = uibuttongroup(app.FrequenceDomainTab);
            app.WindowButtonGroup.Enable = 'off';
            app.WindowButtonGroup.Title = 'Window';
            app.WindowButtonGroup.Visible = 'off';
            app.HannButton = uitogglebutton(app.WindowButtonGroup);
            app.HannButton.Text = 'Hann';
            app.HannButton.Value = true;
            app.HammingButton = uitogglebutton(app.WindowButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.PSDmetodButtonGroup = uibuttongroup(app.FrequenceDomainTab);
            app.PSDmetodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PSDmetodButtonGroupSelectionChanged, true);
            app.PSDmetodButtonGroup.Title = 'PSD metod';
            app.PeriodogramButton = uitogglebutton(app.PSDmetodButtonGroup);
            app.PeriodogramButton.Text = 'Periodogram';
            app.PeriodogramButton.Value = true;
            app.WelchButton = uitogglebutton(app.PSDmetodButtonGroup);
            app.WelchButton.Text = 'Welch';
            app.OverlapfactorLabel = uilabel(app.FrequenceDomainTab);
            app.OverlapfactorLabel.Enable = 'off';
            app.OverlapfactorLabel.Visible = 'off';
            app.OverlapfactorLabel.Text = 'Overlap factor (%)';
            app.OverlapfactorEditField = uieditfield(app.FrequenceDomainTab, 'numeric');
            app.OverlapfactorEditField.Limits = [0 1];
            app.OverlapfactorEditField.ValueDisplayFormat = '%11.11g';
            app.OverlapfactorEditField.Enable = 'off';
            app.OverlapfactorEditField.Visible = 'off';
            app.OverlapfactorEditField.Value = 0.5;
            app.WindowgainEditFieldLabel = uilabel(app.FrequenceDomainTab);
            app.WindowgainEditFieldLabel.Enable = 'off';
            app.WindowgainEditFieldLabel.Visible = 'off';
            app.WindowgainEditFieldLabel.Text = 'Window gain';
            app.WindowgainEditField = uieditfield(app.FrequenceDomainTab, 'numeric');
            app.WindowgainEditField.Limits = [0 Inf];
            app.WindowgainEditField.ValueDisplayFormat = '%.2f';
            app.WindowgainEditField.ValueChangedFcn = createCallbackFcn(app, @WindowgainEditFieldValueChanged, true);
            app.WindowgainEditField.Enable = 'off';
            app.WindowgainEditField.Visible = 'off';
            app.WindowgainEditField.Value = 0.25;
            app.WindowlengthofsignallengthSliderLabel = uilabel(app.FrequenceDomainTab);
            app.WindowlengthofsignallengthSliderLabel.Enable = 'off';
            app.WindowlengthofsignallengthSliderLabel.Visible = 'off';
            app.WindowlengthofsignallengthSliderLabel.Text = {'Window length'; '(% of signal length )'};
            app.WindowlengthofsignallengthSlider = uislider(app.FrequenceDomainTab);
            app.WindowlengthofsignallengthSlider.MajorTicks = [0 25 50 75 100];
            app.WindowlengthofsignallengthSlider.ValueChangedFcn = createCallbackFcn(app, @WindowlengthofsignallengthSliderValueChanged, true);
            app.WindowlengthofsignallengthSlider.Enable = 'off';
            app.WindowlengthofsignallengthSlider.Visible = 'off';
            app.WindowlengthofsignallengthSlider.Value = 50;
            app.WindowNum = uieditfield(app.FrequenceDomainTab, 'numeric');
            app.WindowNum.Limits = [0.001 100];
            app.WindowNum.ValueDisplayFormat = '%.2f';
            app.WindowNum.ValueChangedFcn = createCallbackFcn(app, @WindowNumValueChanged, true);
            app.WindowNum.Enable = 'off';
            app.WindowNum.Visible = 'off';
            app.WindowNum.Value = 50;
            app.CalculateButton = uibutton(app.FrequenceDomainTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.SpectralProcessingToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralProcessingToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralProcessingToolUIFigure)
        end
    end
end